classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Menu        matlab.ui.container.Menu
        Menu_3      matlab.ui.container.Menu
        CH1Menu     matlab.ui.container.Menu
        CH2Menu     matlab.ui.container.Menu
        Menu_4      matlab.ui.container.Menu
        Menu_5      matlab.ui.container.Menu
        Menu_6      matlab.ui.container.Menu
        Menu_2      matlab.ui.container.Menu
        Menu_7      matlab.ui.container.Menu
        Menu_9      matlab.ui.container.Menu
        CH1Menu_4   matlab.ui.container.Menu
        CH2Menu_4   matlab.ui.container.Menu
        MATLABMenu  matlab.ui.container.Menu
        CH1Menu_5   matlab.ui.container.Menu
        CH2Menu_5   matlab.ui.container.Menu
        Menu_8      matlab.ui.container.Menu
        Menu_12     matlab.ui.container.Menu
        Menu_13     matlab.ui.container.Menu
        Menu_14     matlab.ui.container.Menu
        FFTMenu     matlab.ui.container.Menu
        CH1Menu_6   matlab.ui.container.Menu
        CH2Menu_6   matlab.ui.container.Menu
        Menu_10     matlab.ui.container.Menu
        Menu_11     matlab.ui.container.Menu
        Spinner     matlab.ui.control.Spinner
        Label_3     matlab.ui.control.Label
        Label_2     matlab.ui.control.Label
        Label       matlab.ui.control.Label
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %其他窗口的句柄
        generate_signal_app
        conv_app
        signal_synthesis_app
        signature_app
        
        %定时器
        Timer_record %用于录制信号
        Timer_genesig %用于产生信号
        Timer_sigcomb %用于合成信号

        %录制信号使用的私有变量
        H
        sampleRate = 44100
        timeLength = 0.05
        samples
        displayTime = 2
        displaySamples=88200
        buffer
        timeArray
        pic
        %产生信号使用的私有变量
        gsBuffer
        gsNewData=0
        gsCount=0
        gsBufferLength=88200/40
        gschirp
        gsChirpbuffer
        gsChirpCount=0
        gsflag=1
        gspic
        %用于信号合成的私有变量
        ssBuffer
        sspic


        CH1Buffer=0%用于存储CH1所有的录制数据
        CH2Buffer=0%用于存储CH2所有的录制数据
        analysis_flag%用于判断是否进行过录制
        kk=1%信号放大的增益值
        
    end
    
    properties (Access = public)
        %用于产生信号
        signal_type=1
        channel=1
        T=1
        %用于信号合成
        channell=1
        method=1
        %用于卷积
        N1=0.5
        N2=0.5
        N3=0
        channelll=1
        %用于信号特征
        snf=0 %频率
        snTT=0 %周期
        snpeak=0 %峰值
        snp2p=0 %峰峰值
        snmean=0 %平均值
        snrmsd=0 %均方差
        snvar=0 %方差
        snrms=0 %rms

    end
    
    methods (Access = private)
        
        function capture(app,~,~,temp)%录制信号定时器的回调函数
            % 从音频设备采集数据
            audioIn = step(app.H)*10*app.kk;
            % 将数据放置在maxbuffer中
            if temp==1
                app.CH1Buffer=[app.CH1Buffer,audioIn'];
            elseif temp==2
                app.CH2Buffer=[app.CH2Buffer,audioIn'];
            end
            % 更新缓冲区：将新数据追加到缓冲区末尾，并移除旧数据
            app.buffer = [app.buffer(app.samples+1:end), audioIn'];
            
            % 更新振幅图
            app.pic.YData = app.buffer;
            
            % 刷新图形
            drawnow limitrate;  
        end

        function setupTimer_record(app,temp)%启动录制信号定时器


            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            
            % 如果音频设备读取对象存在，先释放它
            if isobject(app.H)
                release(app.H);
            end
            
            % 创建音频设备读取对象
            app.H = audioDeviceReader(...
                'DeviceName', '主声音捕获驱动程序', ...
                'NumChannels', 1, ...
                'BitDepth', '16-bit integer', ...
                'SamplesPerFrame', app.samples, ...
                'SampleRate', app.sampleRate);
            %将分析标志置为0
            app.analysis_flag=0;
            % 创建缓冲区
            app.buffer = zeros(1, app.displaySamples);
            %创建绘制对象
            app.timeArray = linspace(-app.displayTime, 0, app.displaySamples);
            if temp==1
                app.pic = plot(app.UIAxes, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            elseif temp==2
                app.pic = plot(app.UIAxes2, app.timeArray, zeros(1, app.displaySamples));
                set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            end
            % 创建定时器对象
            app.Timer_record = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.timeLength, ...
                'TimerFcn', @(src, event) capture(app, src, event,temp));
        end
    
        function axes_generate_signal(app,~,~)%产生信号定时器的回调函数
            if app.signal_type==1
                % 生成方波数据
                if app.gsCount<app.T/0.1
                    app.gsCount=app.gsCount+1;
                    if app.gsflag==1
                        app.gsNewData=0.8;
                    else
                        app.gsNewData=0;
                    end
                else
                    app.gsCount=0;
                    if app.gsflag==1
                        app.gsflag=0;
                    elseif app.gsflag==0
                        app.gsflag=1;
                    end
                end
            elseif app.signal_type==2
                %生成正弦波
                t=2*pi*0.05/app.T;
                app.gsCount=app.gsCount+t;
                app.gsNewData=sin(app.gsCount);
            elseif app.signal_type==4
                %生成chirp信号
                app.gsChirpbuffer=chirp(app.gschirp,1000,300,2000);
                app.gsChirpCount=app.gsChirpCount+1;
                app.gsNewData=app.gsChirpbuffer(app.gsChirpCount);
            elseif app.signal_type==3
                %生成阶跃信号
                app.gsNewData=0.8;
            end
            %更新到总buffer中
            if app.channel==1
                app.CH1Buffer=[app.CH1Buffer,app.gsNewData];
            elseif app.channel==2
                app.CH2Buffer=[app.CH2Buffer,app.gsNewData];
            end
            %信号缓存
            app.gsBuffer = [app.gsBuffer(2:end), app.gsNewData];  
            
            %更新图像
            app.gspic.YData = app.gsBuffer;
            
            % 刷新图形
            drawnow limitrate;  
        end
        
        function setupTimer_genesig(app)%启动产生信号定时器
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
            

            % 初始化缓冲区和时间轴
            app.gsBuffer = zeros(1, app.gsBufferLength);
            app.gschirp = linspace(0, 1, app.gsBufferLength);


            %创建绘制对象
            app.timeArray = linspace(-app.displayTime, 0, app.gsBufferLength);
            if app.channel==1
                app.gspic = plot(app.UIAxes, app.timeArray, zeros(1, app.gsBufferLength));
                set(app.UIAxes, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            elseif app.channel==2
                app.gspic = plot(app.UIAxes2, app.timeArray, zeros(1, app.gsBufferLength));
                set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);
            end
            % 创建定时器对象
            app.Timer_genesig = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) axes_generate_signal(app,src,event));
        end


    end
    
    methods (Access = public)
        
        function start_generate_signal(app)
            setupTimer_genesig(app);
            start(app.Timer_genesig);
        end
        
        function start_signal_synthesis(app)
            %先关闭其他两个图像生成
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
            %规定ssBuffer的数组长度
            if Length(app.CH1Buffer)>=Length(app.CH2Buffer)
                app.ssBuffer = zeros(1, Length(app.CH1Buffer));
            else
                app.ssBuffer = zeros(1, Length(app.CH2Buffer));
            end
            %根据情况为每一个元素赋值
            for i=1:Length(app.ssBuffer)
                if app.method==1
                    app.ssBuffer(i)=app.CH1Buffer(i)+app.CH2Buffer(i);
                elseif app.method==2
                    app.ssBuffer(i)=app.CH1Buffer(i)-app.CH2Buffer(i);
                elseif app.method==3
                    app.ssBuffer(i)=app.CH2Buffer(i)-app.CH1Buffer(i);
                elseif app.method==4
                    app.ssBuffer(i)=app.CH1Buffer(i)*app.CH2Buffer(i);
                end
            end
            if app.channell==1
                plot(app.UIAxes, app.ssBuffer);
                title(app.UIAxes, '合成结果');
                axis(app.UIAxes, 'tight');
            elseif app.channell==2
                plot(app.UIAxes2, app.ssBuffer);
                title(app.UIAxes2, '合成结果');
                axis(app.UIAxes2, 'tight');
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.Menu_5.Enable="off";
            app.generate_signal_app=generate_signal(app);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)

            app.analysis_flag=1;
            app.CH1Menu.Enable="on";
            app.CH2Menu.Enable="on";
            if ~isempty(app.Timer_record) && isvalid(app.Timer_record)
                stop(app.Timer_record);
                delete(app.Timer_record);
            end
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            if 0%ALL(app.CH1Buffer==0) || ALL(app.CH2Buffer==0)
                app.Label_2.Text ="信号合成之前请先确定两个通道都有信号";
            else
                app.Menu_8.Enable="off";
                app.signal_synthesis_app=signal_synthesis(app);
            end

        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            app.Menu_11.Enable="off";
            app.conv_app=conv(app);
        end

        % Menu selected function: CH1Menu_4
        function CH1Menu_4Selected(app, event)
            %该回调是自定义函数通道一
            if app.CH1Buffer==0
                app.Label_2.Text="通道一无数据";
            else 

                %计算峰值
                peakvalue2 = app.CH1Buffer(1);
                for i = 2:length(app.CH1Buffer)
                    if app.CH1Buffer(i) > peakvalue2
                        peakvalue2 = app.CH1Buffer(i);
                    end
                end

                %计算方差
                sumvalue2 = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    sumvalue2 = sumvalue2 + app.CH1Buffer(i);
                end
                meanvalue2 = sumvalue2 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH1Buffer(i) - meanvalue2)^2;
                end
                variancevalue = squared_diff_sum / n;
                
                %计算峰峰值
                maxvalue = app.CH1Buffer(1);
                minvalue = app.CH1Buffer(1);
                for i = 2:length(app.CH1Buffer)
                    if app.CH1Buffer(i) > maxvalue
                        maxvalue = app.CH1Buffer(i);
                    end
                    if app.CH1Buffer(i) < minvalue
                        minvalue = app.CH1Buffer(i);
                    end
                end
                peak2peakvalue2 = maxvalue - minvalue;  
                
                % 计算均方差
                sumvalue1 = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    sumvalue1 = sumvalue1 + app.C1Buffer(i);
                end
                meanvalue = sumvalue1 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH1Buffer(i) - meanvalue)^2;
                end
                mean_squared_diff = squared_diff_sum / n;
                rmsdvalue1 = sqrt(mean_squared_diff);

                %计算RMS
                squared_sum = 0;
                n = length(app.CH1Buffer);
                for i = 1:n
                    squared_sum = squared_sum + app.CH1Buffer(i)^2;
                end
                mean_squared = squared_sum / n;
                rmsvalue1 = sqrt(mean_squared);

                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH1Buffer);
                fft_result = fft(app.CH1Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=meanvalue2;
                app.snp2p=peak2peakvalue2;
                app.snpeak=peakvalue2;
                app.snrmsd=rmsdvalue1;
                app.snrms=rmsvalue1;
                app.snvar=variancevalue;
                % 在标签中显示最大峰值
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);
            end
        end

        % Menu selected function: CH2Menu_4
        function CH2Menu_4Selected(app, event)
            %该回调是自定义函数通道二
            if app.CH2Buffer==0
                app.Label_2.Text="通道二无数据";
            else 

                %计算峰值
                peakvalue2 = app.CH2Buffer(1);
                for i = 2:length(app.CH2Buffer)
                    if app.CH2Buffer(i) > peakvalue2
                        peakvalue2 = app.CH2Buffer(i);
                    end
                end

                %计算方差
                sumvalue2 = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    sumvalue2 = sumvalue2 + app.CH2Buffer(i);
                end
                meanvalue2 = sumvalue2 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH2Buffer(i) - meanvalue2)^2;
                end
                variancevalue = squared_diff_sum / n;
                
                %计算峰峰值
                maxvalue = app.CH2Buffer(1);
                minvalue = app.CH2Buffer(1);
                for i = 2:length(app.CH2Buffer)
                    if app.CH2Buffer(i) > maxvalue
                        maxvalue = app.CH2Buffer(i);
                    end
                    if app.CH2Buffer(i) < minvalue
                        minvalue = app.CH2Buffer(i);
                    end
                end
                peak2peakvalue2 = maxvalue - minvalue;  
                
                % 计算均方差
                sumvalue1 = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    sumvalue1 = sumvalue1 + app.CH2Buffer(i);
                end
                meanvalue = sumvalue1 / n;
                squared_diff_sum = 0;
                for i = 1:n
                    squared_diff_sum = squared_diff_sum + (app.CH2Buffer(i) - meanvalue)^2;
                end
                mean_squared_diff = squared_diff_sum / n;
                rmsdvalue1 = sqrt(mean_squared_diff);

                %计算RMS
                squared_sum = 0;
                n = length(app.CH2Buffer);
                for i = 1:n
                    squared_sum = squared_sum + app.CH2Buffer(i)^2;
                end
                mean_squared = squared_sum / n;
                rmsvalue1 = sqrt(mean_squared);

                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH2Buffer);
                fft_result = fft(app.CH2Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=meanvalue2;
                app.snp2p=peak2peakvalue2;
                app.snpeak=peakvalue2;
                app.snrmsd=rmsdvalue1;
                app.snrms=rmsvalue1;
                app.snvar=variancevalue;
                % 在标签中显示最大峰值
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);
            end
        end

        % Menu selected function: CH1Menu_5
        function CH1Menu_5Selected(app, event)
            %该回调是MATLAB函数通道一
            %先检验buffer是否可用
            if app.CH1Buffer==0
                app.Label_2.Text="通道一无数据";
            else
                %计算数值
                peakvalue = max(abs(app.CH1Buffer));
                peak2peakvalue = max(app.CH1Buffer) - min(app.CH1Buffer);               
                rmsvalue = sqrt(mean(app.CH1Buffer.^2));
                signalvar = var(app.CH1Buffer);
                signalmean = mean(app.CH1Buffer);
                rmsdvalue = sqrt(mean((app.CH1Buffer - signalmean).^2));
               
                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH1Buffer);
                fft_result = fft(app.CH1Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=signalmean;
                app.snp2p=peak2peakvalue;
                app.snpeak=peakvalue;
                app.snrmsd=rmsdvalue;
                app.snrms=rmsvalue;
                app.snvar=signalvar;
    
                %关闭打开按钮
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);       
            end            
        end

        % Menu selected function: CH2Menu_5
        function CH2Menu_5Selected(app, event)
            %该回调是MATLAB函数通道二
            %先检验buffer是否可用
            if app.CH2Buffer==0
                app.Label_2.Text="通道二无数据";
            else
                %计算数值
                peakvalue = max(abs(app.CH2Buffer));
                peak2peakvalue = max(app.CH2Buffer) - min(app.CH2Buffer);               
                rmsvalue = sqrt(mean(app.CH2Buffer.^2));
                signalvar = var(app.CH2Buffer);
                signalmean = mean(app.CH2Buffer);
                rmsdvalue = sqrt(mean((app.CH2Buffer - signalmean).^2));
                
                % 计算傅里叶变换
                dt=0.05;
                N = length(app.CH2Buffer);
                fft_result = fft(app.CH2Buffer);
                freq = (0:N-1)*(1/(N*dt)); % dt 是采样间隔
                
                % 计算幅度谱
                amplitude = abs(fft_result);
                
                % 找到主频率
                [~, idx] = max(amplitude);
                main_freq = freq(idx);
                
                % 计算周期并传递周期和频率
                if main_freq > 0
                    period = 1 / main_freq;
                    app.snf=main_freq; 
                    app.snTT=period;
                else
                    app.snf=404; 
                    app.snTT=404;
                end
                %传递其他值
                app.snmean=signalmean;
                app.snp2p=peak2peakvalue;
                app.snpeak=peakvalue;
                app.snrmsd=rmsdvalue;
                app.snrms=rmsvalue;
                app.snvar=signalvar;
    
                %关闭打开按钮
                app.CH1Menu_4.Enable="off";
                app.CH1Menu_5.Enable="off";
                app.CH2Menu_4.Enable="off";
                app.CH2Menu_5.Enable="off";
                app.signature_app=signature(app);       
            end
        end

        % Menu selected function: CH1Menu
        function CH1MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            
            
            % 设置定时器
            setupTimer_record(app,1);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Menu selected function: CH2Menu
        function CH2MenuSelected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            
            
            % 设置定时器
            setupTimer_record(app,2);
            
            % 启动定时器
            start(app.Timer_record);
            %关闭按钮
            app.CH1Menu.Enable="off";
            app.CH2Menu.Enable="off";
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.kk=changingValue;
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            if ~isempty(app.Timer_genesig) && isvalid(app.Timer_genesig)
                stop(app.Timer_genesig);
                delete(app.Timer_genesig);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '信号';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.Text = '开始录制信号';
            app.CH1Menu = uimenu(app.Menu_3);
            app.CH1Menu.MenuSelectedFcn = createCallbackFcn(app, @CH1MenuSelected, true);
            app.CH1Menu.Text = 'CH1';
            app.CH2Menu = uimenu(app.Menu_3);
            app.CH2Menu.MenuSelectedFcn = createCallbackFcn(app, @CH2MenuSelected, true);
            app.CH2Menu.Text = 'CH2';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '暂停录制信号';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '信号发生器';
            app.Menu_6 = uimenu(app.Menu);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '暂停发生信号';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形';
            app.Menu_7 = uimenu(app.Menu_2);
            app.Menu_7.Text = '波形特征';
            app.Menu_9 = uimenu(app.Menu_7);
            app.Menu_9.Text = '自定义函数';
            app.CH1Menu_4 = uimenu(app.Menu_9);
            app.CH1Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_4Selected, true);
            app.CH1Menu_4.Text = 'CH1';
            app.CH2Menu_4 = uimenu(app.Menu_9);
            app.CH2Menu_4.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_4Selected, true);
            app.CH2Menu_4.Text = 'CH2';
            app.MATLABMenu = uimenu(app.Menu_7);
            app.MATLABMenu.Text = 'MATLAB函数';
            app.CH1Menu_5 = uimenu(app.MATLABMenu);
            app.CH1Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH1Menu_5Selected, true);
            app.CH1Menu_5.Text = 'CH1';
            app.CH2Menu_5 = uimenu(app.MATLABMenu);
            app.CH2Menu_5.MenuSelectedFcn = createCallbackFcn(app, @CH2Menu_5Selected, true);
            app.CH2Menu_5.Text = 'CH2';
            app.Menu_8 = uimenu(app.Menu_2);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '信号合成';
            app.Menu_12 = uimenu(app.UIFigure);
            app.Menu_12.Text = '高级计算';
            app.Menu_13 = uimenu(app.Menu_12);
            app.Menu_13.Text = '卷积';
            app.Menu_14 = uimenu(app.Menu_12);
            app.Menu_14.Text = '相关';
            app.FFTMenu = uimenu(app.UIFigure);
            app.FFTMenu.Text = 'FFT';
            app.CH1Menu_6 = uimenu(app.FFTMenu);
            app.CH1Menu_6.Text = 'CH1';
            app.CH2Menu_6 = uimenu(app.FFTMenu);
            app.CH2Menu_6.Text = 'CH2';
            app.Menu_10 = uimenu(app.UIFigure);
            app.Menu_10.Text = '滤波';
            app.Menu_11 = uimenu(app.Menu_10);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '卷积核';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'CH1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'CH2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '提示：';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '信号振幅放大';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end