classdef signal_synthesis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Button_4       matlab.ui.control.Button
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        Button         matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        CH2Button      matlab.ui.control.RadioButton
        CH1Button      matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        CallingApp
        method % Description
        channell
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %将值赋给中间变量
            if app.Button.Value==1
                app.method=1;
            elseif app.Button_2.Value==1
                app.method=2;
            elseif app.Button_3.Value==1
                app.method=3;
            end
            if app.CH1Button.Value==1
                app.channell=1;
            elseif app.CH2Button.Value==1
                app.channell=2;
            end
            %传递给main中的变量
            app.CallingApp.channell=app.channell;
            app.CallingApp.method=app.method;
            %重新打开按钮
            app.CallingApp.Menu_8.Enable="on";
            %关闭此页面
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '频道';
            app.CH1Button = uiradiobutton(app.ButtonGroup);
            app.CH1Button.Text = 'CH1';
            app.CH1Button.Value = true;
            app.CH2Button = uiradiobutton(app.ButtonGroup);
            app.CH2Button.Text = 'CH2';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = '运算';
            app.Button = uiradiobutton(app.ButtonGroup_2);
            app.Button.Text = '+';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_2.Text = '-';
            app.Button_3 = uiradiobutton(app.ButtonGroup_2);
            app.Button_3.Text = '*';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_synthesis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end