classdef signature < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.Button
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main)
            app.CallingApp=main;
            % 使用字符串数组拼接
            textLines = [ ...
                "该信号的峰值为：" + num2str(app.CallingApp.peak, '%.8f'), ...
                "峰峰值为：" + num2str(app.CallingApp.p2p, '%.8f'), ...
                "平均值为：" + num2str(app.CallingApp.mean, '%.8f'), ...
                "方差为：" + num2str(app.CallingApp.var, '%.8f'), ...
                "均方差为：" + num2str(app.CallingApp.rmsd, '%.8f'), ...
                "RMS为：" + num2str(app.CallingApp.rms, '%.8f'), ...
                "", ...
                "根据傅里叶变换：", ...
                "信号的主频率为：" + num2str(app.CallingApp.f, '%.8f'), ...
                "对应周期为：" + num2str(app.CallingApp.TT, '%.8f')
                ];
            
            % 使用 join 拼接为多行文本
            app.Label.Text = join(textLines, newline);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.CallingApp.CH1Menu_4.Enable="on";
            app.CallingApp.CH1Menu_5.Enable="on";
            app.CallingApp.CH2Menu_4.Enable="on";
            app.CallingApp.CH2Menu_5.Enable="on";
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '确认';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signature(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end