classdef cfy20224392 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Menu       matlab.ui.container.Menu
        Menu_3     matlab.ui.container.Menu
        Menu_7     matlab.ui.container.Menu
        chirpMenu  matlab.ui.container.Menu
        Menu_8     matlab.ui.container.Menu
        Menu_2     matlab.ui.container.Menu
        Menu_4     matlab.ui.container.Menu
        Menu_5     matlab.ui.container.Menu
        Menu_6     matlab.ui.container.Menu
        Menu_9     matlab.ui.container.Menu
        Menu_12    matlab.ui.container.Menu
        Menu_11    matlab.ui.container.Menu
        Menu_10    matlab.ui.container.Menu
        Spinner    matlab.ui.control.Spinner
        Label      matlab.ui.control.Label
        Button     matlab.ui.control.Button
        UIAxes3    matlab.ui.control.UIAxes
        UIAxes1    matlab.ui.control.UIAxes
        UIAxes2    matlab.ui.control.UIAxes
    end

    %此处声明私有属性
    properties (Access = private)
        %用于捕捉麦克风
        Timer_2
        H
        sampleRate = 44100
        timeLength = 0.05
        samples
        displayTime = 2
        displaySamples
        buffer
        timeArray
        pic
        %用于生成信号
        Timer_3
        signalbuffer
        t
        count=0
        flag=1
        newDataPoint=0
        signal_flag
        ychirp
        chripcount=0
        %用于处理运算
        Timer_1
        process_flag
        process_buffer
        process_newdata=0
        k
        %用于信号分析
        j=1
    end

    %此处声明私有函数
    methods (Access = private)
        %此处处理定时器Timer_2，用于捕捉麦克风信号
        function capture(app,~,~)
            % 从音频设备采集数据
            audioIn = step(app.H)*app.j;
            
            % 更新缓冲区：将新数据追加到缓冲区末尾，并移除旧数据
            app.buffer = [app.buffer(app.samples+1:end), audioIn'];
            
            % 更新振幅图
            app.pic.YData = app.buffer;
            
            % 刷新图形
            drawnow limitrate;  
        end
        function setupTimer_2(app)


            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            
            % 如果音频设备读取对象存在，先释放它
            if isobject(app.H)
                release(app.H);
            end
            
            % 创建音频设备读取对象
            app.H = audioDeviceReader(...
                'DeviceName', '主声音捕获驱动程序', ...
                'NumChannels', 1, ...
                'BitDepth', '16-bit integer', ...
                'SamplesPerFrame', app.samples, ...
                'SampleRate', app.sampleRate);
            
            % 创建缓冲区
            app.buffer = zeros(1, app.displaySamples);
            app.timeArray = linspace(-app.displayTime, 0, app.displaySamples);

            % 在UIAxes2上创建图形
            app.pic = plot(app.UIAxes2, app.timeArray, zeros(1, app.displaySamples));
            set(app.UIAxes2, 'xlim', [-app.displayTime 0], 'ylim', [-1 1], 'XTick', [], 'YTick', []);

            % 创建定时器对象
            app.Timer_2 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', app.timeLength, ...
                'TimerFcn', @(src, event) capture(app, src, event));
        end
        %此处处理定时器Timer_3，用于生成信号
        function signal_generate(app)
            if app.signal_flag==0
                % 生成方波数据
                if app.count<10
                    app.count=app.count+1;
                else
                    if app.flag==1
                        app.flag=0;
                        app.newDataPoint=0.5;
                    else
                        app.flag=1;
                        app.newDataPoint=0;
                    end
                    app.count=0;
                end
            elseif app.signal_flag==1
                %生成正弦波
                app.count=app.count+0.05;
                app.newDataPoint=sin(app.count);
            elseif app.signal_flag==2
                %生成chirp信号
                app.ychirp=chirp(app.t,1000,300,2000);
                app.chripcount=app.chripcount+1;
                app.newDataPoint=app.ychirp(app.chripcount);
            elseif app.signal_flag==3
                %生成阶跃信号
                app.newDataPoint=0.8;
                if app.count<10
                    app.newDataPoint=0;
                    app.count=app.count+1;
                end
            end   
            % 更新图像数据
            app.signalbuffer = [app.signalbuffer(2:end), app.newDataPoint];  
            plot(app.UIAxes1,app.t, app.signalbuffer);
            xlim(app.UIAxes1, [0 1]);  % 设置 X 轴范围
            ylim(app.UIAxes1, [-1 1]);  % 设置 Y 轴范围
        end
        function setupTimer_3(app)
            % 如果定时器已存在，先停止并删除它
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            bufferLength = 300;  % 缓冲区长度
            if isempty(app.signalbuffer)
                % 初始化缓冲区和时间轴
                
                app.signalbuffer = zeros(1, bufferLength);
                app.t = linspace(0, 1, bufferLength);
            end
            app.newDataPoint = 0; 
            % 创建定时器对象
            app.Timer_3 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) signal_generate(app));
        end
        %此处处理定时器Timer_1，用于处理运算
        function process(app)
            if app.process_flag==0    
                app.process_newdata=app.buffer(end)+app.signalbuffer(end);
            elseif app.process_flag==1
                app.process_newdata=app.buffer(end)-app.signalbuffer(end);
            elseif app.process_flag==2
                app.process_newdata=app.buffer(end)*app.signalbuffer(end);
            end
            app.process_buffer = [app.process_buffer(2:end), app.process_newdata];  
            plot(app.UIAxes3,app.t, app.process_buffer);
            xlim(app.UIAxes3, [0 1]);  % 设置 X 轴范围
            ylim(app.UIAxes3, [-1 1]);  % 设置 Y 轴范围
        end
        function setupTimer_1(app)
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            if isempty(app.process_buffer)
                % 初始化缓冲区和时间轴
                app.process_buffer = zeros(1, 300);
                app.k = linspace(0, 1, 300);
            end 
            % 创建定时器对象
            app.Timer_1 = timer(...
                'ExecutionMode', 'fixedRate', ...
                'Period', 0.05, ...
                'TimerFcn', @(src, event) process(app));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function CloseButton_Fcn(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Callback function
        function PulseButton_Fcn(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
        end

        % Callback function
        function StartButton_Fcn(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_2(app);
            
            % 启动定时器
            start(app.Timer_2);
        end

        % Menu selected function: Menu_3
        function Menu_3Selected(app, event)
            app.signal_flag=0;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
            app.signal_flag=1;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: chirpMenu
        function chirpMenuSelected(app, event)
            app.signal_flag=2;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
            app.signal_flag=3;
            setupTimer_3(app);
            start(app.Timer_3);
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            app.process_flag=0;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
            app.process_flag=1;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
            app.process_flag=2;
            setupTimer_1(app);
            start(app.Timer_1);
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Menu selected function: Menu_11
        function Menu_11Selected(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            % 初始化定时器参数
            app.samples = floor(app.timeLength * app.sampleRate);
            app.displaySamples = app.displayTime * app.sampleRate;
            
            % 设置定时器
            setupTimer_2(app);
            
            % 启动定时器
            start(app.Timer_2);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isempty(app.Timer_2) && isvalid(app.Timer_2)
                stop(app.Timer_2);
                delete(app.Timer_2);
            end
            if ~isempty(app.Timer_3) && isvalid(app.Timer_3)
                stop(app.Timer_3);
                delete(app.Timer_3);
            end
            if ~isempty(app.Timer_1) && isvalid(app.Timer_1)
                stop(app.Timer_1);
                delete(app.Timer_1);
            end
            % 释放音频设备
            if isobject(app.H)
                release(app.H);
            end
            
            % 关闭应用程序窗口
            close(app.UIFigure);
        end

        % Value changing function: Spinner
        function SpinnerValueChanging(app, event)
            changingValue = event.Value;
            app.j=changingValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '产生波形';
            app.Menu_3 = uimenu(app.Menu);
            app.Menu_3.MenuSelectedFcn = createCallbackFcn(app, @Menu_3Selected, true);
            app.Menu_3.Text = '方波';
            app.Menu_7 = uimenu(app.Menu);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '正弦波';
            app.chirpMenu = uimenu(app.Menu);
            app.chirpMenu.MenuSelectedFcn = createCallbackFcn(app, @chirpMenuSelected, true);
            app.chirpMenu.Text = 'chirp';
            app.Menu_8 = uimenu(app.Menu);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '阶跃信号';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '波形运算';
            app.Menu_4 = uimenu(app.Menu_2);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '+';
            app.Menu_5 = uimenu(app.Menu_2);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '-';
            app.Menu_6 = uimenu(app.Menu_2);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '*';
            app.Menu_9 = uimenu(app.UIFigure);
            app.Menu_9.Text = '麦克风录制';
            app.Menu_12 = uimenu(app.Menu_9);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '关闭';
            app.Menu_11 = uimenu(app.Menu_9);
            app.Menu_11.MenuSelectedFcn = createCallbackFcn(app, @Menu_11Selected, true);
            app.Menu_11.Text = '暂停';
            app.Menu_10 = uimenu(app.Menu_9);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '开始';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '麦克风录制')
            xlabel(app.UIAxes2, '时间')
            ylabel(app.UIAxes2, '振幅')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes1 = uiaxes(app.UIFigure);
            title(app.UIAxes1, '产生波形')
            xlabel(app.UIAxes1, '时间')
            ylabel(app.UIAxes1, '振幅')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, '波形运算')
            xlabel(app.UIAxes3, '时间')
            ylabel(app.UIAxes3, '振幅')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '关闭';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '麦克风信号缩放';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.ValueChangingFcn = createCallbackFcn(app, @SpinnerValueChanging, true);
            app.Spinner.Limits = [0 100];
            app.Spinner.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cfy20224392

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end